classdef moveInset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        XSliderLabel                   matlab.ui.control.Label
        XSlider                        matlab.ui.control.Slider
        YSliderLabel                   matlab.ui.control.Label
        YSlider                        matlab.ui.control.Slider
        MoveInsetLabel                 matlab.ui.control.Label
        DoneButton                     matlab.ui.control.Button
        ResizeWidthSliderLabel         matlab.ui.control.Label
        ResizeWidthSlider              matlab.ui.control.Slider
        AxisSpinnerLabel               matlab.ui.control.Label
        AxisSpinner                    matlab.ui.control.Spinner
        ResizeHeightSliderLabel        matlab.ui.control.Label
        ResizeHeightSlider             matlab.ui.control.Slider
        LinkWidthandHeightSwitchLabel  matlab.ui.control.Label
        LinkWidthandHeightSwitch       matlab.ui.control.Switch
        Xeditfield                     matlab.ui.control.NumericEditField
        Yeditfield                     matlab.ui.control.NumericEditField
        Weditfield                     matlab.ui.control.NumericEditField
        Heditfield                     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        fig;
        axs; 
        axsCC; 
        ax2Move;  
        ax2MoveStartPosition; 
    end
    
    methods (Access = private)
        
        function setAxisSpinner(app)
            app.axs = findobj(app.fig, 'type', 'axes'); 
            nAxes = length(app.axs); 
            app.AxisSpinner.Limits = [1 nAxes];
        end
        function getAxesColors(app)
            app.axsCC = get(app.axs, 'color');
        end
        function setXYWH(app)
            app.XSlider.Value = app.ax2Move.Position(1);
            app.Xeditfield.Value = app.XSlider.Value;
            app.YSlider.Value = app.ax2Move.Position(2);
            app.Yeditfield.Value = app.YSlider.Value; 
            app.ResizeWidthSlider.Value = app.ax2Move.Position(3);
            app.Weditfield.Value = app.ResizeWidthSlider.Value; 
            app.ResizeHeightSlider.Value = app.ax2Move.Position(4);
            app.Heditfield.Value = app.ResizeHeightSlider.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fig)
            app.fig = fig;
            app.setAxisSpinner(); 
            app.getAxesColors();
            app.AxisSpinnerValueChanged();
            app.setXYWH();
            waitfor(app);
        end

        % Callback function: DoneButton, UIFigure
        function UIFigureCloseRequest(app, event)
            for iAx = 1:length(app.axs)
                set(app.axs(iAx), 'color', app.axsCC{iAx});
            end
            delete(app)
        end

        % Value changed function: AxisSpinner
        function AxisSpinnerValueChanged(app, event)
            value = app.AxisSpinner.Value;
            app.ax2Move = app.axs(value);
            app.ax2MoveStartPosition = get(app.ax2Move, 'position');
            for iAx = 1:length(app.axs)
                if iAx == value
                    set(app.axs(iAx), 'color', [0 0 1 0.3]);
                else
                    set(app.axs(iAx), 'color', app.axsCC{iAx});
                end
            end
            app.setXYWH()
        end

        % Value changing function: XSlider
        function XSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Xeditfield.Value = changingValue; 
            currentPos = get(app.ax2Move, 'position');
            set(app.ax2Move, 'position', [changingValue, currentPos(2:4)]);
            app.ax2MoveStartPosition = get(app.ax2Move, 'position');
        end

        % Value changing function: YSlider
        function YSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Yeditfield.Value = changingValue; 
            currentPos = get(app.ax2Move, 'position');
            set(app.ax2Move, 'position', [currentPos(1), changingValue, currentPos(3:4)]);
            app.ax2MoveStartPosition = get(app.ax2Move, 'position');
        end

        % Value changing function: ResizeWidthSlider
        function ResizeWidthSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Weditfield.Value = changingValue;
            switch app.LinkWidthandHeightSwitch.Value
                case 'On'
                    app.ResizeHeightSlider.Value = changingValue; 
                    app.Heditfield.Value = changingValue;
                    set(app.ax2Move, 'position', [app.ax2MoveStartPosition(1:2), changingValue, changingValue]);
                case 'Off'
                    set(app.ax2Move, 'position', [app.ax2MoveStartPosition(1:2), changingValue, app.ResizeHeightSlider.Value]);
            end
        end

        % Value changing function: ResizeHeightSlider
        function ResizeHeightSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Heditfield.Value = changingValue;
            switch app.LinkWidthandHeightSwitch.Value
                case 'On'
                    app.ResizeWidthSlider.Value = changingValue; 
                    app.Weditfield.Value = changingValue;
                    set(app.ax2Move, 'position', [app.ax2MoveStartPosition(1:2), changingValue, changingValue]);
                case 'Off'
                    set(app.ax2Move, 'position', [app.ax2MoveStartPosition(1:2), app.ResizeWidthSlider.Value, changingValue]);
            end
        end

        % Value changed function: Xeditfield
        function XeditfieldValueChanged(app, event)
            value = app.Xeditfield.Value;
            app.XSlider.Value = value; 
            sliderEvent = struct('Value', value);
            app.XSliderValueChanging(sliderEvent);
        end

        % Value changed function: Yeditfield
        function YeditfieldValueChanged(app, event)
            value = app.Yeditfield.Value;
            app.YSlider.Value = value; 
            sliderEvent = struct('Value', value);
            app.YSliderValueChanging(sliderEvent); 
        end

        % Value changed function: Weditfield
        function WeditfieldValueChanged(app, event)
            value = app.Weditfield.Value;
            app.ResizeWidthSlider.Value = value;
            sliderEvent = struct('Value', value);
            app.ResizeWidthSliderValueChanging(sliderEvent); 
        end

        % Value changed function: Heditfield
        function HeditfieldValueChanged(app, event)
            value = app.Heditfield.Value;
            app.ResizeHeightSlider.Value = value; 
            sliderEvent = struct('Value', value);
            app.ResizeHeightSliderValueChanging(sliderEvent);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [0 1];
            app.XSlider.ValueChangingFcn = createCallbackFcn(app, @XSliderValueChanging, true);
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.Limits = [0 1];
            app.YSlider.ValueChangingFcn = createCallbackFcn(app, @YSliderValueChanging, true);
            app.MoveInsetLabel = uilabel(app.UIFigure);
            app.MoveInsetLabel.Text = 'Move Inset';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DoneButton.Text = 'Done';
            app.ResizeWidthSliderLabel = uilabel(app.UIFigure);
            app.ResizeWidthSliderLabel.Text = 'Resize Width';
            app.ResizeWidthSlider = uislider(app.UIFigure);
            app.ResizeWidthSlider.Limits = [0 1];
            app.ResizeWidthSlider.ValueChangingFcn = createCallbackFcn(app, @ResizeWidthSliderValueChanging, true);
            app.AxisSpinnerLabel = uilabel(app.UIFigure);
            app.AxisSpinnerLabel.Text = 'Axis';
            app.AxisSpinner = uispinner(app.UIFigure);
            app.AxisSpinner.ValueChangedFcn = createCallbackFcn(app, @AxisSpinnerValueChanged, true);
            app.ResizeHeightSliderLabel = uilabel(app.UIFigure);
            app.ResizeHeightSliderLabel.Text = 'Resize Height';
            app.ResizeHeightSlider = uislider(app.UIFigure);
            app.ResizeHeightSlider.Limits = [0 1];
            app.ResizeHeightSlider.ValueChangingFcn = createCallbackFcn(app, @ResizeHeightSliderValueChanging, true);
            app.LinkWidthandHeightSwitchLabel = uilabel(app.UIFigure);
            app.LinkWidthandHeightSwitchLabel.Text = 'Link Width and Height';
            app.LinkWidthandHeightSwitch = uiswitch(app.UIFigure, 'slider');
            app.LinkWidthandHeightSwitch.Value = 'On';
            app.Xeditfield = uieditfield(app.UIFigure, 'numeric');
            app.Xeditfield.ValueDisplayFormat = '%.2f';
            app.Xeditfield.ValueChangedFcn = createCallbackFcn(app, @XeditfieldValueChanged, true);
            app.Yeditfield = uieditfield(app.UIFigure, 'numeric');
            app.Yeditfield.ValueDisplayFormat = '%.2f';
            app.Yeditfield.ValueChangedFcn = createCallbackFcn(app, @YeditfieldValueChanged, true);
            app.Weditfield = uieditfield(app.UIFigure, 'numeric');
            app.Weditfield.ValueDisplayFormat = '%.2f';
            app.Weditfield.ValueChangedFcn = createCallbackFcn(app, @WeditfieldValueChanged, true);
            app.Heditfield = uieditfield(app.UIFigure, 'numeric');
            app.Heditfield.ValueDisplayFormat = '%.2f';
            app.Heditfield.ValueChangedFcn = createCallbackFcn(app, @HeditfieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moveInset(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end